classdef Proj1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        getY                   matlab.ui.control.Button
        EntropyEditField       matlab.ui.control.EditField
        ExitButton             matlab.ui.control.Button
        EntropyEditFieldLabel  matlab.ui.control.Label
        SelectButton           matlab.ui.control.Button
        fileSel                matlab.ui.control.EditField
        EnterthepathofatextfileEditFieldLabel  matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        originalText = ""; % This keeps hold of the file we select
        decodedText = ""; % The file we receive from the BSC_p after decoding.
        % Bits sent and received over the BSC_p
        fileSentBits = "";
        fileReceivedBits = "";

        charList = char(strcat(char(65:90),char(97:122), char(49:57), ",. "));
        % charList contains all the possible chars we have in any given
        % sample test case. we can quickly find the binary code for a
        % charachter by finding its index in the array then converting it
        % into binary.

    end

    methods (Access = private)

        function y = sendOverBSCp(app, bit)
            % Returns the reuslt "y" of a bit sent over a binary symmetric
            % channel with error probability = p.
            p = 0;
            random = rand(); % Returns a value between 0 & 1 by default.
            if (random > p)
                % The random value is larger than the probability of error,
                % We send back the bit unchanged.
                if(bit == '1')
                    y = true;
                else
                    y = false;
                end
                return
            else
                % Random value is less than p.
                % make the error.
                if(bit == '0')
                    y = true;
                else
                    y = false;
                end
            end


        end

        function binaryCode = getLetterCode(app, char)
            % Find the index of the given charachter in our charList,
            % then convert the index to binary.
            binaryCode = dec2bin(strfind(app.charList,char) - 1,6);
            % Minus one because MATLAB, the best programming language ever
            % made, starts its indicies from ONE, not ZERO.
        end

        function charDecoded = decodeChar(app, bitStream)
            idx = bin2dec(bitStream)+1;
            charDecoded = app.charList(idx);
        end

        function fileDecoded = decodeFile(app, bitStream)
            idx = 1;
            for i = 1:5:length(bitStream)-5
                fileDecoded(idx) = app.decodeChar(char(extractBetween(bitStream, i+1, i+5)));
                idx = idx + 1;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            [file , path] = uigetfile ('*.txt') ;
            app.originalText = fileread (strcat(path,file));
            app.fileSel.Value = path ;

            %Entropy FN%

            % Checks whether temp is a character array
            if (ischar(app.originalText)==1)
                l=length(app.originalText);
                uniqueChars = unique(app.originalText);   % String temp has all unique characters sorted
                lenChar=length(uniqueChars);
                f=zeros(1,lenChar);
                for i=1:lenChar
                    % Count the occurence of unique characters
                    f(i)=length(findstr(app.originalText,uniqueChars(i)));
                end
                p=zeros(1,lenChar);
                for i=1:lenChar
                    %  Probabilities for each unique character
                    p(i)=f(i)/l;
                end
                H=0;
                for  i=1:lenChar
                    % Calculating the Entropy
                    H = H + (-p(i)*log2(p(i)));
                end
            end
            %  Entropy FN end       %
            %print entropy
            app.EntropyEditField.Value = num2str(H);
            figure (app.UIFigure);
        end

        % Value changed function: fileSel
        function fileSelValueChanged(app, event)
            value = app.fileSel.Value;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % plot(app.UIAxes,pdf(temp,[0 10]));

        end

        % Value changed function: EntropyEditField
        function EntropyEditFieldValueChanged(app, event)
            value = app.EntropyEditField.Value;

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: getY
        function getYPushed(app, event)

            % Convert each char of our file into it's 6-bit code.
            app.fileSentBits = cell2mat(arrayfun(@app.getLetterCode,app.originalText, 'UniformOutput', false));

            % Send the file on the binary symmetric channel with error
            % probability p

            zeroASCII = 48;
            bitStreamLogicalArray = arrayfun(@app.sendOverBSCp, app.fileSentBits);
            app.fileReceivedBits = char(double(bitStreamLogicalArray)+zeroASCII);

            disp(app.originalText)
            disp(app.decodeFile(app.fileReceivedBits))

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Probability Distribution')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.EnterthepathofatextfileEditFieldLabel = uilabel(app.UIFigure);
            app.EnterthepathofatextfileEditFieldLabel.Text = 'Enter the path of a text file';
            app.fileSel = uieditfield(app.UIFigure, 'text');
            app.fileSel.ValueChangedFcn = createCallbackFcn(app, @fileSelValueChanged, true);
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.EntropyEditFieldLabel = uilabel(app.UIFigure);
            app.EntropyEditFieldLabel.Text = 'Entropy';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.EntropyEditField = uieditfield(app.UIFigure, 'text');
            app.EntropyEditField.ValueChangedFcn = createCallbackFcn(app, @EntropyEditFieldValueChanged, true);
            app.EntropyEditField.Editable = 'off';
            app.getY = uibutton(app.UIFigure, 'push');
            app.getY.ButtonPushedFcn = createCallbackFcn(app, @getYPushed, true);
            app.getY.Text = 'Generate Faulty Output';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Proj1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end